classdef Lab_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ProgramdozakrywaniadanychosobistychwplikachDICOMLabel  matlab.ui.control.Label
        BrakLabel         matlab.ui.control.Label
        StatusLabel_1     matlab.ui.control.Label
        ZapiszplikButton  matlab.ui.control.Button
        UkryjdaneButton   matlab.ui.control.Button
        WczytajButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajButton
        function WczytajButtonPushed(app, event)
           [filename,filepath] = uigetfile('C:\Users\jacza\OneDrive\Pulpit\DICOM Example\*.dcm', 'Wybierz plik DICOM');
           global dicomFile
           dicomFile = [filepath, filename];
           global I
           I = dicomread(dicomFile);

           app.BrakLabel.Text = "Wczytano plik"
        end

        % Button pushed function: UkryjdaneButton
        function UkryjdaneButtonPushed(app, event)
           global dicomFile
           global metadata
           metadata = dicominfo(dicomFile);
           metadata.PatientName.FamilyName = 'Unknown';
           metadata.PatientID = 'Unknown';

           app.BrakLabel.Text = "Ukryto dane osobiste"
        end

        % Button pushed function: ZapiszplikButton
        function ZapiszplikButtonPushed(app, event)
            global I
            global metadata
            dicomwrite(I ,"Unknown.dcm", metadata);

            app.BrakLabel.Text = "Zapisano nowy plik"
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WczytajButton = uibutton(app.UIFigure, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajButtonPushed, true);
            app.WczytajButton.Text = 'Wczytaj';
            app.UkryjdaneButton = uibutton(app.UIFigure, 'push');
            app.UkryjdaneButton.ButtonPushedFcn = createCallbackFcn(app, @UkryjdaneButtonPushed, true);
            app.UkryjdaneButton.Text = 'Ukryj dane';
            app.ZapiszplikButton = uibutton(app.UIFigure, 'push');
            app.ZapiszplikButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszplikButtonPushed, true);
            app.ZapiszplikButton.Text = 'Zapisz plik';
            app.StatusLabel_1 = uilabel(app.UIFigure);
            app.StatusLabel_1.Text = 'Status :';
            app.BrakLabel = uilabel(app.UIFigure);
            app.BrakLabel.Text = 'Brak';
            app.ProgramdozakrywaniadanychosobistychwplikachDICOMLabel = uilabel(app.UIFigure);
            app.ProgramdozakrywaniadanychosobistychwplikachDICOMLabel.Text = 'Program do zakrywania danych osobistych w plikach DICOM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end