classdef Zadanie_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Jasnosc                       matlab.ui.control.Slider
        ZmianaszarociLabel            matlab.ui.control.Label
        OdwrceniekoloruSwitch         matlab.ui.control.Switch
        OdwrceniekoloruSwitchLabel    matlab.ui.control.Label
        Binaryzacja                   matlab.ui.control.Slider
        ZmianabinaryzacjiSliderLabel  matlab.ui.control.Label
        PodgldszarociLabel            matlab.ui.control.Label
        PodgldbinaryzacjiLabel        matlab.ui.control.Label
        PodgldoryginauLabel           matlab.ui.control.Label
        WybierzobrazLabel             matlab.ui.control.Label
        WczytajButton                 matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global  changingValue2
            changingValue2 = 1;
            global changingValue1
            changingValue2 = 0;
        end

        % Button pushed function: WczytajButton
        function Wczytanie(app, event)
           [filename,filepath] = uigetfile('C:\Users\jacza\OneDrive\Pulpit\Zdjęcia\*.png', 'Chuj Ci na ryj... A teraz wybierz zdjęcie')
           Image = [filepath, filename]
           RGB = imread(Image)
           
           global Gray 
           Gray = rgb2gray(RGB)
           value = app.Binaryzacja.Value;
           BW = imbinarize(Gray,'adaptive','ForegroundPolarity','dark','Sensitivity', value);

           imshow(RGB, 'Parent', app.UIAxes);
           imshow(Gray, 'Parent', app.UIAxes2);
           imshow(BW, 'Parent', app.UIAxes3);
        end

        % Value changed function: Binaryzacja
        function Suwak(app, event)
           global Gray
           %value = app.Binaryzacja.Value;
           %BW = imbinarize(Gray,'adaptive','ForegroundPolarity','dark','Sensitivity', value);
           %imshow(BW, 'Parent', app.UIAxes3);
        end

        % Value changing function: Binaryzacja
        function AktywnySuwak(app, event)
            global changingValue1
            changingValue1 = event.Value;
            global Gray
            value = app.Binaryzacja.Value;
            BW = imbinarize(Gray,'adaptive','ForegroundPolarity','dark','Sensitivity', changingValue1);
            imshow(BW, 'Parent', app.UIAxes3);
        end

        % Value changed function: OdwrceniekoloruSwitch
        function Negacja(app, event)
            value = app.OdwrceniekoloruSwitch.Value;
            if strcmp(value,'On')
                global Gray
                GrayNegacja = 255 - Gray
                imshow(GrayNegacja, 'Parent', app.UIAxes2)
            else
                global Gray
                imshow(Gray, 'Parent', app.UIAxes2) 
            end

        end

        % Value changing function: Jasnosc
        function JasnoscValueChanging(app, event)
            global changingValue2
            changingValue2 = event.Value;
            global Gray
            GrayDouble =  im2double(Gray)
            GrayJasny = GrayDouble + changingValue2 - 1
            imshow(GrayJasny, 'Parent', app.UIAxes2)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.WczytajButton = uibutton(app.UIFigure, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @Wczytanie, true);
            app.WczytajButton.Text = 'Wczytaj';
            app.WybierzobrazLabel = uilabel(app.UIFigure);
            app.WybierzobrazLabel.Text = 'Wybierz obraz';
            app.PodgldoryginauLabel = uilabel(app.UIFigure);
            app.PodgldoryginauLabel.Text = 'Podgląd oryginału';
            app.PodgldbinaryzacjiLabel = uilabel(app.UIFigure);
            app.PodgldbinaryzacjiLabel.Text = 'Podgląd binaryzacji';
            app.PodgldszarociLabel = uilabel(app.UIFigure);
            app.PodgldszarociLabel.Text = 'Podgląd szarości';
            app.ZmianabinaryzacjiSliderLabel = uilabel(app.UIFigure);
            app.ZmianabinaryzacjiSliderLabel.Text = 'Zmiana binaryzacji';
            app.Binaryzacja = uislider(app.UIFigure);
            app.Binaryzacja.Limits = [0 1];
            app.Binaryzacja.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Binaryzacja.ValueChangedFcn = createCallbackFcn(app, @Suwak, true);
            app.Binaryzacja.ValueChangingFcn = createCallbackFcn(app, @AktywnySuwak, true);
            app.Binaryzacja.Value = 0.5;
            app.OdwrceniekoloruSwitchLabel = uilabel(app.UIFigure);
            app.OdwrceniekoloruSwitchLabel.Text = 'Odwrócenie koloru';
            app.OdwrceniekoloruSwitch = uiswitch(app.UIFigure, 'slider');
            app.OdwrceniekoloruSwitch.ValueChangedFcn = createCallbackFcn(app, @Negacja, true);
            app.ZmianaszarociLabel = uilabel(app.UIFigure);
            app.ZmianaszarociLabel.Text = 'Zmiana jasności';
            app.Jasnosc = uislider(app.UIFigure);
            app.Jasnosc.Limits = [0 2];
            app.Jasnosc.ValueChangingFcn = createCallbackFcn(app, @JasnoscValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Zadanie_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end