classdef Lab_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DropDown                     matlab.ui.control.DropDown
        JedenpikseltoEditField       matlab.ui.control.EditField
        JedenpikseltoEditFieldLabel  matlab.ui.control.Label
        WywietlobrazButton           matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        WczytajButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajButton
        function WczytajButtonPushed(app, event)
         [filename,filepath] = uigetfile('C:\Users\jacza\OneDrive\Pulpit\Zdjęcia\*.png', 'Wybierz zdjęcie')
         Image_Script = [filepath, filename]
         global RGB
         RGB = imread(Image_Script);
   
         app.Label.Text = "Wczytano plik"
        end

        % Button pushed function: WywietlobrazButton
        function WywietlobrazButtonPushed(app, event)
            global RGB
            sz = size(RGB);
            global value
            global skala
            myData.Scale = skala;
            myData.Units = value;
            myData.MaxValue = hypot(sz(1),sz(2));
            myData.Colormap = parula;
            myData.ScaleFactor = 1;
            hIm = imshow(RGB);
            hIm.ButtonDownFcn = @(~,~) startDrawing(hIm.Parent,myData);

            app.Label.Text = "Wyświetlenie obrazu"
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            global value
            value = app.DropDown.Value;    
        end

        % Value changed function: JedenpikseltoEditField
        function JedenpikseltoEditFieldValueChanged(app, event)
            global skala
            skala = app.JedenpikseltoEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WczytajButton = uibutton(app.UIFigure, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajButtonPushed, true);
            app.WczytajButton.Text = 'Wczytaj';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' ';
            app.WywietlobrazButton = uibutton(app.UIFigure, 'push');
            app.WywietlobrazButton.ButtonPushedFcn = createCallbackFcn(app, @WywietlobrazButtonPushed, true);
            app.WywietlobrazButton.Text = 'Wyświetl obraz';
            app.JedenpikseltoEditFieldLabel = uilabel(app.UIFigure);
            app.JedenpikseltoEditFieldLabel.Text = 'Jeden piksel to:';
            app.JedenpikseltoEditField = uieditfield(app.UIFigure, 'text');
            app.JedenpikseltoEditField.ValueChangedFcn = createCallbackFcn(app, @JedenpikseltoEditFieldValueChanged, true);
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {' ', 'mm', 'cm', 'm', 'km'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab_7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end