classdef Obrobka < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        WysokoEditField            matlab.ui.control.NumericEditField
        WysokoEditFieldLabel       matlab.ui.control.Label
        BrakLabel                  matlab.ui.control.Label
        StatusLabel                matlab.ui.control.Label
        ZapiszButton               matlab.ui.control.Button
        NanieButton                matlab.ui.control.Button
        Przedzial3bEditField       matlab.ui.control.NumericEditField
        Przedzial3bEditFieldLabel  matlab.ui.control.Label
        Przedzial3aEditField       matlab.ui.control.NumericEditField
        Przedzial3aEditFieldLabel  matlab.ui.control.Label
        Przedzial2bEditField       matlab.ui.control.NumericEditField
        Przedzial2bEditFieldLabel  matlab.ui.control.Label
        Przedzial2aEditField       matlab.ui.control.NumericEditField
        Przedzial2aEditFieldLabel  matlab.ui.control.Label
        Przedzial1bEditField       matlab.ui.control.NumericEditField
        Przedzial1bEditFieldLabel  matlab.ui.control.Label
        Przedzial1aEditField       matlab.ui.control.NumericEditField
        Przedzial1aEditFieldLabel  matlab.ui.control.Label
        EtapDropDown               matlab.ui.control.DropDown
        EtapDropDownLabel          matlab.ui.control.Label
        WczytajButton              matlab.ui.control.Button
        RuchDropDown               matlab.ui.control.DropDown
        RuchDropDownLabel          matlab.ui.control.Label
        PacjentDropDown            matlab.ui.control.DropDown
        PacjentDropDownLabel       matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;
            close all;
            clc;
        end

        % Button pushed function: WczytajButton
        function WczytajButtonPushed(app, event)
            global Etap
            global Pacjent
            global Ruch
            global Dane

            Dane = open(sprintf('D:/Studia/Praca dyplomowa/Krio/AppDesigner/Dane/P%s_%s_%s.mat',Pacjent,Etap,Ruch));
            Dane = eval(sprintf('Dane.P%s_%s_%s',Pacjent,Etap,Ruch));
            plot(app.UIAxes,Dane);
            app.BrakLabel.Text = "Wczytano plik"


        end

        % Value changed function: PacjentDropDown
        function PacjentDropDownValueChanged(app, event)
            global Pacjent
            Pacjent = app.PacjentDropDown.Value;
            
        end

        % Value changed function: RuchDropDown
        function RuchDropDownValueChanged(app, event)
            global Ruch
            Ruch = app.RuchDropDown.Value;
            
        end

        % Value changed function: EtapDropDown
        function EtapDropDownValueChanged(app, event)
            global Etap
            Etap = app.EtapDropDown.Value;
            
        end

        % Value changed function: Przedzial1aEditField
        function Przedzial1aEditFieldValueChanged(app, event)
            global Przedzial1a
            Przedzial1a = app.Przedzial1aEditField.Value;
            
        end

        % Value changed function: Przedzial1bEditField
        function Przedzial1bEditFieldValueChanged(app, event)
            global Przedzial1b
            Przedzial1b = app.Przedzial1bEditField.Value;
            
        end

        % Value changed function: Przedzial2aEditField
        function Przedzial2aEditFieldValueChanged(app, event)
            global Przedzial2a
            Przedzial2a = app.Przedzial2aEditField.Value;
            
        end

        % Value changed function: Przedzial2bEditField
        function Przedzial2bEditFieldValueChanged(app, event)
            global Przedzial2b
            Przedzial2b = app.Przedzial2bEditField.Value;
            
        end

        % Value changed function: Przedzial3aEditField
        function Przedzial3aEditFieldValueChanged(app, event)
            global Przedzial3a
            Przedzial3a = app.Przedzial3aEditField.Value;
            
        end

        % Value changed function: Przedzial3bEditField
        function Przedzial3bEditFieldValueChanged(app, event)
            global Przedzial3b
            Przedzial3b = app.Przedzial3bEditField.Value;
            
        end

        % Button pushed function: NanieButton
        function NanieButtonPushed(app, event)
            global Przedzial1a
            global Przedzial1b
            global Przedzial2a
            global Przedzial2b
            global Przedzial3a 
            global Przedzial3b 
            global Srednia
            global Dane
            global Wysokosc

            Przetworzone = Dane + Wysokosc;
            Wynik_1 = Przetworzone(Przedzial1a:Przedzial1b);
            Wynik_2 = Przetworzone(Przedzial2a:Przedzial2b);
            Wynik_3 = Przetworzone(Przedzial3a:Przedzial3b);
            X_Wynik_1 = mean(Wynik_1);
            X_Wynik_2 = mean(Wynik_2);
            X_Wynik_3 = mean(Wynik_3);
            Srednia = round((X_Wynik_1 + X_Wynik_2 + X_Wynik_3)/3,2);
            for(i=1:1:length(Przetworzone))
            Min{1,i} = Srednia;
            end
            Min = cell2mat(Min);

            plot(app.UIAxes_2, Przetworzone);
            hold(app.UIAxes_2);
            plot(app.UIAxes_2, Min,'r');
            plot(app.UIAxes_2, Przedzial1a:Przedzial1b, Przetworzone(Przedzial1a:Przedzial1b),'b','LineWidth',3);
            plot(app.UIAxes_2, Przedzial2a:Przedzial2b, Przetworzone(Przedzial2a:Przedzial2b),'b','LineWidth',3);
            plot(app.UIAxes_2, Przedzial3a:Przedzial3b, Przetworzone(Przedzial3a:Przedzial3b),'b','LineWidth',3);
            text(app.UIAxes_2, length(Przetworzone),Srednia+Srednia/40,num2str(Srednia),"Color",'r')


        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)
            global Ruch
            global Etap
            global Pacjent
            global Przedzial1a
            global Przedzial1b
            global Przedzial2a
            global Przedzial2b
            global Przedzial3a 
            global Przedzial3b
            global Srednia
            global Ustawienia

            Ustawienia(1,1) = Srednia;
            Ustawienia(2,1) = Przedzial1a;
            Ustawienia(3,1) = Przedzial1b;
            Ustawienia(4,1) = Przedzial2a;
            Ustawienia(5,1) = Przedzial2b;
            Ustawienia(6,1) = Przedzial3a;
            Ustawienia(7,1) = Przedzial3b;

            
            save(sprintf('D:/Studia/Praca dyplomowa/Krio/AppDesigner/Ustawienia/Ustawienia_P%s_%s_%s.mat',Pacjent,Etap,Ruch),"Ustawienia");
            app.BrakLabel.Text = "Zapisano plik"


        end

        % Value changed function: WysokoEditField
        function WysokoEditFieldValueChanged(app, event)
            global Wysokosc
            Wysokosc = app.WysokoEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Surowe dane')
            xlabel(app.UIAxes, 'Czas [ms]')
            ylabel(app.UIAxes, 'Kąt [deg]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Przerobione dane')
            xlabel(app.UIAxes_2, 'Czas [ms]')
            ylabel(app.UIAxes_2, 'Kąt [deg]')
            zlabel(app.UIAxes_2, 'Z')
            app.PacjentDropDownLabel = uilabel(app.UIFigure);
            app.PacjentDropDownLabel.Text = 'Pacjent';
            app.PacjentDropDown = uidropdown(app.UIFigure);
            app.PacjentDropDown.Items = {' ', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.PacjentDropDown.ValueChangedFcn = createCallbackFcn(app, @PacjentDropDownValueChanged, true);
            app.PacjentDropDown.Value = ' ';
            app.RuchDropDownLabel = uilabel(app.UIFigure);
            app.RuchDropDownLabel.Text = 'Ruch';
            app.RuchDropDown = uidropdown(app.UIFigure);
            app.RuchDropDown.Items = {' ', 'Ewersja', 'Inwersja', 'Podeszwowe', 'Grzbietowe'};
            app.RuchDropDown.ValueChangedFcn = createCallbackFcn(app, @RuchDropDownValueChanged, true);
            app.RuchDropDown.Value = ' ';
            app.WczytajButton = uibutton(app.UIFigure, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajButtonPushed, true);
            app.WczytajButton.Text = 'Wczytaj';
            app.EtapDropDownLabel = uilabel(app.UIFigure);
            app.EtapDropDownLabel.Text = 'Etap';
            app.EtapDropDown = uidropdown(app.UIFigure);
            app.EtapDropDown.Items = {' ', 'Normalne', 'Krio'};
            app.EtapDropDown.ValueChangedFcn = createCallbackFcn(app, @EtapDropDownValueChanged, true);
            app.EtapDropDown.Value = ' ';
            app.Przedzial1aEditFieldLabel = uilabel(app.UIFigure);
            app.Przedzial1aEditFieldLabel.Text = 'Przedzial 1a';
            app.Przedzial1aEditField = uieditfield(app.UIFigure, 'numeric');
            app.Przedzial1aEditField.ValueChangedFcn = createCallbackFcn(app, @Przedzial1aEditFieldValueChanged, true);
            app.Przedzial1bEditFieldLabel = uilabel(app.UIFigure);
            app.Przedzial1bEditFieldLabel.Text = 'Przedzial 1b';
            app.Przedzial1bEditField = uieditfield(app.UIFigure, 'numeric');
            app.Przedzial1bEditField.ValueChangedFcn = createCallbackFcn(app, @Przedzial1bEditFieldValueChanged, true);
            app.Przedzial2aEditFieldLabel = uilabel(app.UIFigure);
            app.Przedzial2aEditFieldLabel.Text = 'Przedzial 2a';
            app.Przedzial2aEditField = uieditfield(app.UIFigure, 'numeric');
            app.Przedzial2aEditField.ValueChangedFcn = createCallbackFcn(app, @Przedzial2aEditFieldValueChanged, true);
            app.Przedzial2bEditFieldLabel = uilabel(app.UIFigure);
            app.Przedzial2bEditFieldLabel.Text = 'Przedzial 2b';
            app.Przedzial2bEditField = uieditfield(app.UIFigure, 'numeric');
            app.Przedzial2bEditField.ValueChangedFcn = createCallbackFcn(app, @Przedzial2bEditFieldValueChanged, true);
            app.Przedzial3aEditFieldLabel = uilabel(app.UIFigure);
            app.Przedzial3aEditFieldLabel.Text = 'Przedzial 3a';
            app.Przedzial3aEditField = uieditfield(app.UIFigure, 'numeric');
            app.Przedzial3aEditField.ValueChangedFcn = createCallbackFcn(app, @Przedzial3aEditFieldValueChanged, true);
            app.Przedzial3bEditFieldLabel = uilabel(app.UIFigure);
            app.Przedzial3bEditFieldLabel.Text = 'Przedzial 3b';
            app.Przedzial3bEditField = uieditfield(app.UIFigure, 'numeric');
            app.Przedzial3bEditField.ValueChangedFcn = createCallbackFcn(app, @Przedzial3bEditFieldValueChanged, true);
            app.NanieButton = uibutton(app.UIFigure, 'push');
            app.NanieButton.ButtonPushedFcn = createCallbackFcn(app, @NanieButtonPushed, true);
            app.NanieButton.Text = 'Nanieś';
            app.ZapiszButton = uibutton(app.UIFigure, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Text = 'Zapisz';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status :';
            app.BrakLabel = uilabel(app.UIFigure);
            app.BrakLabel.Text = 'Brak';
            app.WysokoEditFieldLabel = uilabel(app.UIFigure);
            app.WysokoEditFieldLabel.Text = 'Wysokość';
            app.WysokoEditField = uieditfield(app.UIFigure, 'numeric');
            app.WysokoEditField.ValueChangedFcn = createCallbackFcn(app, @WysokoEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Obrobka

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end